classdef UHS_CCT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        PushTool_save                  matlab.ui.container.toolbar.PushTool
        PushTool_saveAs                matlab.ui.container.toolbar.PushTool
        PushTool_open                  matlab.ui.container.toolbar.PushTool
        PushTool_info                  matlab.ui.container.toolbar.PushTool
        PushTool_help                  matlab.ui.container.toolbar.PushTool
        Panel                          matlab.ui.container.Panel
        Label_notification             matlab.ui.control.Label
        ResettoDefaultsButton          matlab.ui.control.Button
        ClearAllButton                 matlab.ui.control.Button
        StorageSettingsPanel           matlab.ui.container.Panel
        InjGasHydrogenRatioEditField   matlab.ui.control.NumericEditField
        InjGasHydrogenRatioEditFieldLabel  matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        MaxPressureBarEditField        matlab.ui.control.NumericEditField
        MaxPressureBarEditFieldLabel   matlab.ui.control.Label
        MinPressureBarEditField        matlab.ui.control.NumericEditField
        MinPressureBarEditFieldLabel   matlab.ui.control.Label
        TabGroup2                      matlab.ui.container.TabGroup
        ReservoirPropsTab              matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        GasCompTab_2                   matlab.ui.container.Tab
        UITable_2                      matlab.ui.control.Table
        CalculateButton                matlab.ui.control.Button
        BuildReservoirModelButton      matlab.ui.control.Button
        OutputsPanel                   matlab.ui.container.Panel
        GasCompositionButton           matlab.ui.control.Button
        pZvsGIPButton                  matlab.ui.control.Button
        ReservoirPropertiesButton      matlab.ui.control.Button
        HydrogenRatioinReservoirEditField  matlab.ui.control.NumericEditField
        HydrogenRatioinResLabel        matlab.ui.control.Label
        HydrogenStorageCapacityMilSm3EditField  matlab.ui.control.NumericEditField
        HydrogenStorageCapacityMilSm3EditFieldLabel  matlab.ui.control.Label
        WorkingGasCapacityMilSm3EditField  matlab.ui.control.NumericEditField
        WorkingGasCapacityMilSm3Label  matlab.ui.control.Label
        ofStorageCyclesEditField       matlab.ui.control.NumericEditField
        NumberofCyclesLabel            matlab.ui.control.Label
        CuhsionGasCompbyCycleButton    matlab.ui.control.Button
        PressureDistributionButton     matlab.ui.control.Button
        DPlotButton                    matlab.ui.control.Button
    end


    properties (Access = private)
        res_model;
        mrst_started;

        %save, open, save as.. functionality
        CurrentFileName = '';  % Variable to store the current file path

        reservoir_pressure_depleted;  % in bar
        reservoir_pressure_injected;  % in bar
        injection_gas_h2_ratio; %in percent

        gas_component_names;

        %outputs

        final_gas_composition;
        final_gas_composition_afterFirstCycle;        
        final_h2_volume_in_reservoir;

        output_depletedState_P_avg;
        output_depletedState_pore_volume;
        output_depletedState_Z_avg ;
        output_depletedState_FVF_avg ;
        output_depletedState_GIP ;
        output_depletedState_H2_volume;
        output_depletedState_H2_ratio;

        output_depletedState_P_avg_initial;
        output_depletedState_pore_volume_initial;
        output_depletedState_Z_avg_initial ;
        output_depletedState_FVF_avg_initial ;
        output_depletedState_GIP_initial ;
        output_depletedState_H2_volume_initial;
        output_depletedState_H2_ratio_initial;

        output_injectedState_P_avg;
        output_injectedState_pore_volume;
        output_injectedState_Z_avg;
        output_injectedState_FVF_avg;
        output_injectedState_GIP;
        output_injectedState_H2_volume;
        output_injectedState_H2_ratio;

        output_final_WGC;
        output_final_h2_storage_capacity;
        output_final_h2ratio_in_reservoir;

        output_injectedState_P_avg_afterFirstCycle;
        output_injectedState_pore_volume_afterFirstCycle;
        output_injectedState_Z_avg_afterFirstCycle;
        output_injectedState_FVF_avg_afterFirstCycle;
        output_injectedState_GIP_afterFirstCycle;
        output_injectedState_H2_volume_afterFirstCycle;
        output_injectedState_H2_ratio_afterFirstCycle;

        output_final_WGC_afterFirstCycle;
        output_final_h2_storage_capacity_afterFirstCycle;
        output_final_h2ratio_in_reservoir_afterFirstCycle;

        %gas comp. change by cycle;
        is_data_available = zeros(1,5);
        cushion_gas_composition = zeros(11,5);

       
    end

    methods (Access = private)

        %function to save input data to file
        function SaveAppData(app, filename)
            % Collect data from tables and text fields
            table1_data = app.UITable.Data;
            table2_data = app.UITable_2.Data;
            
            min_pressure_data=app.MinPressureBarEditField.Value;
            max_pressure_data=app.MaxPressureBarEditField.Value;
            inj_gas_h2_ratio_data = app.Slider.Value;
         
            % Save data to a .mat file
            save(filename, "table1_data", "table2_data", "min_pressure_data", "max_pressure_data", "inj_gas_h2_ratio_data");
            uialert(app.UIFigure, 'File saved successfully!', 'Success', 'Icon', 'success');

            app.Label_notification.Text = "File saved successfully!";

            drawnow;
            figure(app.UIFigure);
          
        end

        %fuction to open previously saved file
        function LoadAppData(app, filename)

            % Create a progress dialog in the app
            d = uiprogressdlg(app.UIFigure, 'Title', 'Please wait...', ...
                'Message', 'Opening file..', 'Indeterminate', 'on');
           
             try

                 loadedData = load(filename);

                 if isfield(loadedData, 'table1_data')

                     % Update tables and text fields with loaded data

                     app.UITable.Data = loadedData.table1_data;
                     app.UITable_2.Data = loadedData.table2_data;

                     app.MinPressureBarEditField.Value = loadedData.min_pressure_data;
                     app.MaxPressureBarEditField.Value = loadedData.max_pressure_data;
                     %app.InjGasHydrogenRatioEditField.Value = loadedData.inj_gas_h2_ratio_data;

                     app.Slider.Value = loadedData.inj_gas_h2_ratio_data;


                     uialert(app.UIFigure, 'File opened successfully.', 'Success', 'Icon', 'success');
                     app.Label_notification.Text = "File opened successfully.";
                     %uiwait(msgbox('File saved successfully!', 'Save Complete', 'modal'));

                     drawnow;
                     figure(app.UIFigure);


                   app.clear_outputPanel();


                 end


             catch ME
                    % Close the dialog in case of error and display the error message
                    close(d);
                    uialert(app.UIFigure, ME.message, 'Error');
                    
                    app.Label_notification.Text="Error";

                    drawnow;
                    figure(app.UIFigure);
             end
         
        end

        % initialize input table (reservoir properties table) 
        function init_input_table1 (app, mode)

            %table1: table for reservoir data

            %mode 1: create table with data
            %mode 0: create table with no data

            if(mode==1)

                data = {'Area, (km²)', 1.0; 'Thickness, (m)', 25;'Porosity, (%)', 16;'Permeability, (mD)', 20;'Water Saturation, (%)', 15;'Rock Comp., (1/Bar)', 4.35e-5;'Temperature, (°C)', 57};

                % Format the numbers to 2 decimal places using sprintf
                for i = 1:5
                    data{i, 2} = sprintf('%.1f', data{i, 2}); % Format each number
                end
                data{6, 2} = sprintf('%.2e', data{6, 2}); % Format each number
                data{7, 2} = sprintf('%.1f', data{7, 2}); % Format each number

            else
                data = {'Area, (km²)', ''; 'Thickness, (m)', '';'Porosity, (%)', '';'Permeability, (mD)', '';'Water Saturation, (%)', '';'Rock Comp., (1/Bar)', '';'Temperature, (°C)', ''};

            end

            app.UITable.ColumnEditable=[false, true];
            app.UITable.Data =  data; 
            
        end

         % initialize input table (gas comp. table) 
        function init_input_table2 (app, mode)

            %table2: table for gas composition

            %mode 1: create table with data
            %mode 0: create table with no data
       
            if(mode==1)
                data2 = {90; 3; 1; 1; 1; 1; 1; 1; 0.5; 0.5; 0.0; 100.0};
            else
                data2 = {0; 0; 0; 0; 0; 0; 0; 0; 0; 0; 0; 0};
            end
           

            app.UITable_2.ColumnEditable=true;
            app.UITable_2.RowName = app.gas_component_names;
            app.UITable_2.RowName(12) = {"Total"};
            app.UITable_2.Data =  data2;

          
        end

       % fuction to calculate GIP at depleted state
        function func_X_no_injection(app, pressure_, gas_comp_)

            % Create a progress dialog in the app
            d = uiprogressdlg(app.UIFigure, 'Title', 'Please wait...', ...
                'Message', 'Calculating output values...', 'Indeterminate', 'on');

            % Try-catch to ensure the dialog closes even if an error occurs
            try

                %calculate GIP in depleted state

                app.res_model = app.res_model.build_fluid_model(gas_comp_, pressure_);        

            catch ME
                % Close the dialog in case of error and display the error message
                close(d);
                uialert(app.UIFigure, ME.message, 'Error');
                 app.Label_notification.Text="Error";

            end

        end

         % fuction to calculate GIP after gas injection. (with iteration)
        function func_X_with_injection(app, p_depleted, p_injected, initial_gas_comp, inj_h2_ratio, cycle)

            if(cycle>1)
                msg = ['Calculating output values...(Cycle: ', num2str(cycle), ')'];
            else
                msg = 'Calculating output values...';
            end 


            % Create a progress dialog in the app
            d = uiprogressdlg(app.UIFigure, 'Title', 'Please wait...', ...
                'Message', msg, 'Indeterminate', 'on');

            % Try-catch to ensure the dialog closes even if an error occurs
            try
                %calculate GIP in depleted state
                app.res_model = app.res_model.build_fluid_model(initial_gas_comp, p_depleted);
                gip_depleted_=app.res_model.get_GIP();

                % *** outputs ***
                app.output_depletedState_P_avg = app.res_model.get_avg_res_pressure();
                app.output_depletedState_pore_volume = app.res_model.get_total_poreVolume()/1e6;
                app.output_depletedState_Z_avg = app.res_model.get_z_factor();
                app.output_depletedState_FVF_avg = app.res_model.get_gas_Bg();
                app.output_depletedState_GIP = app.res_model.get_GIP();
                app.output_depletedState_H2_volume = app.res_model.get_GIP() *initial_gas_comp(11);
                app.output_depletedState_H2_ratio = (app.output_depletedState_H2_volume / app.output_depletedState_GIP) * 100;

                %calculate GIP in injected state

                %step 1: initial guess for h2 ratio
                h2ratio_in_reservoir = (1 - (p_depleted/p_injected)) * inj_h2_ratio;

                initial_h2_volume = gip_depleted_ * initial_gas_comp(11);
                

                for iterasyon_i = 1:20

                    %step 2: determine new gas composition considering the H2 ratio in the reservoir

                    h2_ratio_change = h2ratio_in_reservoir - initial_gas_comp(11);

                    gas_composition = initial_gas_comp *(1-h2_ratio_change);

                    total_NG_fraction=0;
                    for i =1: (numel(gas_composition)-1)
                        total_NG_fraction = total_NG_fraction + gas_composition(i);
                    end
                    gas_composition(11)  = 1-total_NG_fraction;

                    % step 3: calculate GIP considering the new gas composition
                    app.res_model = app.res_model.build_fluid_model(gas_composition, p_injected);

                    gip_after_injection=app.res_model.get_GIP();

                    % step 4: calculate H2 volume in reservoir considering
                    % the calculated GIP and the injected amount of h2

                    h2_volume_in_reservoir = (gip_after_injection - gip_depleted_)*inj_h2_ratio + initial_h2_volume;

                    % step 5: calculate a new H2 ratio considering the calcualated GIP and H2 volume
                    h2ratio_in_reservoir = h2_volume_in_reservoir  / gip_after_injection;   %  ((gip_after_injection - gip_depleted)/gip_after_injection)*input_inj_gas_h2_ratio + initial_h2_ratio;

                    % step 6: check difference in guessed and calculated h2 ratio
                    diff_in_percent = abs((h2ratio_in_reservoir - gas_composition(11)))*100;

                    % step 7: if the difference is less than 0.1% finish calculation else repeat starting from step 1.
                    % use calculated H2 ratio for the initial (guessed) value of H2 ratio

                    if( diff_in_percent <0.5 )
                       
                        break;
                    end
                end


                % *** outputs ***

                app.final_gas_composition = gas_composition;
                
                %disp( gas_composition);
                app.final_h2_volume_in_reservoir = h2_volume_in_reservoir;
                app.output_final_h2ratio_in_reservoir = h2ratio_in_reservoir;

                app.output_injectedState_P_avg = app.res_model.get_avg_res_pressure();
                app.output_injectedState_pore_volume = app.res_model.get_total_poreVolume()/1e6;
                app.output_injectedState_Z_avg = app.res_model.get_z_factor();
                app.output_injectedState_FVF_avg = app.res_model.get_gas_Bg();
                app.output_injectedState_GIP =app.res_model.get_GIP();
                app.output_injectedState_H2_volume = app.final_h2_volume_in_reservoir; % app.res_model.GIP() * initial_gas_composition(11);
                app.output_injectedState_H2_ratio = app.final_gas_composition(11)*100; % (app.output_injectedState_H2_volume / app.output_injectedState_GIP) * 100;

               app.output_final_h2_storage_capacity = h2_volume_in_reservoir - initial_h2_volume;
               app.output_final_WGC = gip_after_injection - gip_depleted_;


            catch ME
                % Close the dialog in case of error and display the error message
                close(d);
                uialert(app.UIFigure, ME.message, 'Error');
                 app.Label_notification.Text="Error";

            end

        end


        function createHelpPopup(app)
    % Get the main app's position
    mainAppPos = app.UIFigure.Position; % [x, y, width, height]
    
    % Define the size of the help popup window
    popupWidth = 400;
    popupHeight = 300;
    
    % Calculate the popup's position to center it relative to the main app
    popupX = mainAppPos(1) + (mainAppPos(3) - popupWidth) / 2;
    popupY = mainAppPos(2) + (mainAppPos(4) - popupHeight) / 2;
    
    % Create the help figure
    helpFig = uifigure('Position', [popupX, popupY, popupWidth, popupHeight], ...
                       'Name', 'Help', ...
                       'Resize', 'off');
    
    % Add a label for the help page title
    uilabel(helpFig, ...
            'Text', 'How to Use the App', ...
            'Position', [20, 250, 360, 30], ...
            'FontSize', 14, ...
            'FontWeight', 'bold', ...
            'HorizontalAlignment', 'left');
    
    % Add a description of how to use the app
    uitextarea(helpFig, ...
               'Value', {['This App builds a reservoir model based on input parameters and' ...
               ' calculates the working gas capacity of a depleted gas reservoir' ...
               ' for underground hydrogen storage (UHS).'], ...
                         '', ...
                         'To use the App:', ...
                         '1. Enter the reservoir parameters, such as porosity, permeability, and gas composition, in the provided input fields.' ...
                         '2. Click the "Build Reservoir Model" button to build the model', ...
                         '2. Specify the minimum and maximum pressure and injection gas hydrogen ratio data.', ...
                         '3. Click the "Calculate" button to calculate the reservoir’s gas storage capacity.', ...
                         '', ...
                         'The results will be displayed, including the calculated storage', ...
                         'capacity and additional reservoir and gas parameters.'}, ...
               'Position', [20, 50, 360, 200], ...
               'Editable', 'off');
    
    % Add a "Close" button
    uibutton(helpFig, ...
             'Text', 'Close', ...
             'Position', [150, 10, 100, 30], ...
             'ButtonPushedFcn', @(~, ~) close(helpFig));
end

function createInfoPopup(app)
    % Get the main app's position
    mainAppPos = app.UIFigure.Position; % [x, y, width, height]
    
    % Define the size of the information popup window
    popupWidth = 400;
    popupHeight = 300;
    
    % Calculate the popup's position to center it relative to the main app
    popupX = mainAppPos(1) + (mainAppPos(3) - popupWidth) / 2;
    popupY = mainAppPos(2) + (mainAppPos(4) - popupHeight) / 2;
    
    % Create the information figure
    infoFig = uifigure('Position', [popupX, popupY, popupWidth, popupHeight], ...
                       'Name', 'About the App', ...
                       'Resize', 'off');
    
    % Add a label for the information page title
    uilabel(infoFig, ...
            'Text', 'About the App', ...
            'Position', [20, 250, 360, 30], ...
            'FontSize', 14, ...
            'FontWeight', 'bold', ...
            'HorizontalAlignment', 'left');
    
    % Add a description of the app
    uitextarea(infoFig, ...
               'Value', {'This application calculates the gas storage capacity (working gas capacity, WGC) of a depletd gas reservoir for hydrogen storage', ...
                         '', ...
                         'Description of the App:', ...
                         'A reservoir model will be created based on user-provided input parameters,', ...
                         'such as porosity, permeability, and gas storage capacity of the reservoir will be calculated considering the user-provided pressure data.', ...
                         '', ...
                         'Note:', ...
                         'This app uses MATLAB Reservoir Simulation Toolbox (MRST) for building reservoir model and performing calculations.', ...
                         '', ...
                         'Reference for MRST:', ...
                         'K.-A. Lie. An Introduction to Reservoir Simulation Using MATLAB/GNU Octave: User Guide for the MATLAB Reservoir Simulation Toolbox (MRST). ', ...
                         'Cambridge University Press, 2019. www.cambridge.org/9781108492430'}, ...
               'Position', [20, 50, 360, 200], ...
               'Editable', 'off');
    
    % Add a "Close" button
    uibutton(infoFig, ...
             'Text', 'Close', ...
             'Position', [150, 10, 100, 30], ...
             'ButtonPushedFcn', @(~, ~) close(infoFig));
end


function clear_outputPanel(app)

    app.CalculateButton.Enable = "on";
    app.PressureDistributionButton.Enable="off";
    app.DPlotButton.Enable = "off";
    app.CuhsionGasCompbyCycleButton.Enable = "off";
    app.ReservoirPropertiesButton.Enable = "off";
    app.pZvsGIPButton.Enable="off";
    app.GasCompositionButton.Enable="off";

    app.HydrogenStorageCapacityMilSm3EditField.Value=0;
    app.HydrogenRatioinReservoirEditField.Value=0;
    app.WorkingGasCapacityMilSm3EditField.Value=0;


end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.UIFigure.Name = 'UHS Capacity Calculation Tool';

            app.mrst_started = false;

            app.gas_component_names = {'Methane', 'Ethane', 'n-Propane', 'IsoButane', 'n-Butane', 'IsoPentane', 'n-Pentane', 'n-Hexane', 'CarbonDioxide', 'Nitrogen', 'Hydrogen' };


            init_input_table1(app, 1);
            init_input_table2(app, 1);
            %init_output_tables(app);


            app.WorkingGasCapacityMilSm3Label.Text = 'Working Gas Capacity,  (Mil. Sm³)';
            app.HydrogenStorageCapacityMilSm3EditFieldLabel.Text = 'Hydrogen Storage Capacity, (Mil. Sm³)';

            app.Label_notification.Text = "App started successfully. Start building reservoir model.";

        end

        % Button pushed function: BuildReservoirModelButton
        function BuildReservoirModelButtonPushed(app, event)

            % input data

            % Get the entire table-1 data
            tableData = get(app.UITable, 'Data');

            % Access data from rows, column 2
            input_area_in_km2 = str2double(tableData{1, 2});
            input_h_in_m =    str2double(tableData{2, 2});
            input_porosity_in_percent = str2double(tableData{3, 2});
            input_permeability_in_mD = str2double(tableData{4, 2});
            input_SWi_in_percent =   str2double(tableData{5, 2});
            input_rock_comp_in_1_bar = str2double(tableData{6, 2});     %4.35e-5;
            input_res_temperature_in_C = str2double(tableData{7, 2});

            input_formation_top_in_m=0;
            input_n_wells=1;

            % Get table-2 data (without the sum row)
            table2Data = app.UITable_2.Data(1:end-1, :);

            input_gas_composition = cell2mat(table2Data(:, 1));

            %disp(input_gas_composition);
            %disp(sum(input_gas_composition));

            %data validation
            %check gas composition sum

            build_reservoir = false;
            if(sum(input_gas_composition)>100.001 || sum(input_gas_composition)<99.998)

                %msgbox('Error: Total gas composition must be exactly 100%.', 'Sum Error', 'Error');
                uialert(app.UIFigure, 'Error: Total gas composition must be exactly 100%.', 'Error', 'Icon', 'error');
                app.Label_notification.Text = "Error: Total gas composition must be exactly 100%.";

            elseif ~(input_area_in_km2>0)
                uialert(app.UIFigure, 'Error: Invalid input for area', 'Error', 'Icon', 'error');
                app.Label_notification.Text = "Error: Error: Invalid input.";

            elseif ~(input_h_in_m>0)
                uialert(app.UIFigure, 'Error: Invalid input for thickness', 'Error', 'Icon', 'error');
                app.Label_notification.Text = "Error: Error: Invalid input.";

            elseif ~(input_porosity_in_percent>0)
                uialert(app.UIFigure, 'Error: Invalid input for porosity', 'Error', 'Icon', 'error');
                app.Label_notification.Text = "Error: Error: Invalid input.";

            elseif ~(input_permeability_in_mD>0)
                uialert(app.UIFigure, 'Error: Invalid input for permeability', 'Error', 'Icon', 'error');
                app.Label_notification.Text = "Error: Error: Invalid input for area.";

            elseif ~(input_SWi_in_percent>0)
                uialert(app.UIFigure, 'Error: Invalid input for water saturation', 'Error', 'Icon', 'error');
                app.Label_notification.Text = "Error: Error: Invalid input for area.";

            elseif ~(input_rock_comp_in_1_bar>0)
                uialert(app.UIFigure, 'Error: Invalid input for rock compressibility', 'Error', 'Icon', 'error');
                app.Label_notification.Text = "Error: Error: Invalid input for area.";

            elseif ~(input_res_temperature_in_C>0)
                uialert(app.UIFigure, 'Error: Invalid input for temperature', 'Error', 'Icon', 'error');
                app.Label_notification.Text = "Error: Error: Invalid input for area.";


            else
                build_reservoir=true;
            end

            if(build_reservoir)

                %start MRST
                % Create a progress dialog in the app
                d = uiprogressdlg(app.UIFigure, 'Title', 'Please wait...', ...
                    'Message', 'Building reservoir model...', 'Indeterminate', 'on');

                % Try-catch to ensure the dialog closes even if an error occurs
                try

                    %disp("mrst_started:" + app.mrst_started );

                    % % start MRST
                    % 
                     if(app.mrst_started==false)
                         
                         %run("MRST\core\startup.m");
                         
                         startup();
                         app.mrst_started = true;
                     end

                    % build model

                    app.res_model = GasReservoirModel(input_area_in_km2, input_h_in_m, input_porosity_in_percent, input_permeability_in_mD, input_SWi_in_percent, input_formation_top_in_m, input_rock_comp_in_1_bar, input_n_wells, input_res_temperature_in_C, input_gas_composition);

  
                     uialert(app.UIFigure, 'Building completed successfully. Storage capacity can be calculated with different pressure and ratio settings.', 'Success', 'Icon', 'success');
                     %uiwait(msgbox('File saved successfully!', 'Save Complete', 'modal'));

                     drawnow;
                     figure(app.UIFigure);


                    app.clear_outputPanel();


                     app.Label_notification.Text = "Building completed successfully.";


                catch ME
                    % Close the dialog in case of error and display the error message
                    close(d);
                    uialert(app.UIFigure, ME.message, 'Error');
                     app.Label_notification.Text="Error";

                end
            end

        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)

            % form inputs
            app.reservoir_pressure_depleted = app.MinPressureBarEditField.Value;  % in bar
            app.reservoir_pressure_injected = app.MaxPressureBarEditField.Value;  % in bar
           
            slider_value = round(app.Slider.Value);
            app.injection_gas_h2_ratio = slider_value / 100; % app.InjGasHydrogenRatioEditField.Value/100; %in percent

            initial_gas_composition = app.res_model.get_initial_gas_composition();
            initial_gas_composition = transpose(initial_gas_composition);
          
            app.func_X_with_injection(app.reservoir_pressure_depleted, app.reservoir_pressure_injected, initial_gas_composition, app.injection_gas_h2_ratio, 1);

            %outputs 

            app.WorkingGasCapacityMilSm3EditField.Value = app.output_final_WGC;
            app.HydrogenStorageCapacityMilSm3EditField.Value = app.output_final_h2_storage_capacity;
            %app.HydrogenRatioofInjGasEditField.Value = (app.output_final_h2_storage_capacity/app.output_final_WGC)*100;
            app.HydrogenRatioinReservoirEditField.Value = round(app.output_injectedState_H2_ratio,1); % app.final_gas_composition_afterFirstCycle(11)*100;


            %output_table_data{16,2} = temp_string; % sprintf('%.2f', output_table_data{16, 2}); % Format each number

            % Convert to string array with 2 decimal places
            %formattedArray = arrayfun(@(x) sprintf('%.2f', x), output_table_data, 'UniformOutput', false);   
            %output_table_data{1, 2} = sprintf('%.2e', output_table_data{6, 2}); % Format each number


            app.DPlotButton.Enable="on";
            app.PressureDistributionButton.Enable="on";
            app.ofStorageCyclesEditField.Enable="on";
            app.NumberofCyclesLabel.Enable="on";
            app.CuhsionGasCompbyCycleButton.Enable="on";
            app.ReservoirPropertiesButton.Enable="on";
            app.pZvsGIPButton.Enable="on";
            app.GasCompositionButton.Enable="on";


            app.is_data_available = zeros(1,5);  % cushion gas data flag reset

            %store data after first storage cycle
            app.final_gas_composition_afterFirstCycle = app.final_gas_composition;

            app.output_injectedState_P_avg_afterFirstCycle =app.output_injectedState_P_avg;
            app.output_injectedState_pore_volume_afterFirstCycle =app.output_injectedState_pore_volume;
            app.output_injectedState_Z_avg_afterFirstCycle=app.output_injectedState_Z_avg;
            app.output_injectedState_FVF_avg_afterFirstCycle=app.output_injectedState_FVF_avg;
            app.output_injectedState_GIP_afterFirstCycle=app.output_injectedState_GIP;
            app.output_injectedState_H2_volume_afterFirstCycle=app.output_injectedState_H2_volume;
            app.output_injectedState_H2_ratio_afterFirstCycle=app.output_injectedState_H2_ratio;

            app.output_final_WGC_afterFirstCycle=app.output_final_WGC;
            app.output_final_h2_storage_capacity_afterFirstCycle=app.output_final_h2_storage_capacity;
            app.output_final_h2ratio_in_reservoir_afterFirstCycle=app.output_final_h2ratio_in_reservoir;


            app.output_depletedState_P_avg_initial = app.output_depletedState_P_avg;
            app.output_depletedState_pore_volume_initial=app.output_depletedState_pore_volume;
            app.output_depletedState_Z_avg_initial =app.output_depletedState_Z_avg;
            app.output_depletedState_FVF_avg_initial =app.output_depletedState_FVF_avg;
            app.output_depletedState_GIP_initial =app.output_depletedState_GIP;
            app.output_depletedState_H2_volume_initial=app.output_depletedState_H2_volume;
            app.output_depletedState_H2_ratio_initial=app.output_depletedState_H2_ratio;


            uialert(app.UIFigure, 'Output parameters are calculated successfully.', 'Success', 'Icon', 'success');
            
            app.Label_notification.Text = "Output parameters are calculated successfully.";


        end

        % Button pushed function: CuhsionGasCompbyCycleButton
        function CuhsionGasCompbyCycleButtonPushed(app, event)

            cycle_n = app.ofStorageCyclesEditField.Value;

            % end of cycle 1
            if(app.is_data_available(1)==0)
                app.cushion_gas_composition(:,1) = transpose (app.final_gas_composition);
                app.is_data_available(1)=1;
            end

            for n = 2 : cycle_n

                if(app.is_data_available(n)==0)
                    app.func_X_with_injection(app.reservoir_pressure_depleted, app.reservoir_pressure_injected, app.final_gas_composition, app.injection_gas_h2_ratio, n);
                    app.cushion_gas_composition(:, n) = transpose (app.final_gas_composition);
                    app.is_data_available(n) = 1;
                end
               
            end

            table_data = zeros(11,cycle_n+1);

            table_data(:,1) = transpose(app.res_model.get_initial_gas_composition())*100.00;

            for n = 1 : cycle_n
                table_data(:,n+1) = app.cushion_gas_composition(:,n)*100.00;
            end

            % Convert to string array with 2 decimal places
            formattedArray = arrayfun(@(x) sprintf('%.1f', x), table_data, 'UniformOutput', false);           

            %display gas comp.

           % Get the position of the main app window
            mainAppPos = app.UIFigure.Position; % [x, y, width, height]

            % Define the size of the popup window
            popupWidth = 400+cycle_n*50;
            popupHeight = 360;

            % Calculate the position to center the popup relative to the main app
            popupX = mainAppPos(1) + (mainAppPos(3) - popupWidth) / 2;
            popupY = mainAppPos(2) + (mainAppPos(4) - popupHeight) / 2;


            rowNames = app.gas_component_names; 

            columnNames = strings(1,cycle_n+1);

            columnNames(1) = "Initial";

            for i=1:cycle_n
                columnNames(i+1)="Cycle "+i;
            end


             % Create the popup window
            popupFig = uifigure('Position', [popupX, popupY, popupWidth, popupHeight], ... % Adjusted height for title
                'Name', 'Cushion Gas Composition by Storage Cycle', ...
                'Resize', 'off');

            % Add a title above the table
            uilabel(popupFig, ...
                'Text', 'Cushion Gas Composition by Storage Cycle', ...
                'FontSize', 12, ...
                'FontWeight', 'bold', ...
                'HorizontalAlignment', 'left', ...
                'Position', [20, popupHeight-40, 320, 30]); % Position title above the table

            % Create a UITable
            uitable(popupFig, ...
                'Data', formattedArray, ...
                'RowName', rowNames, ...
                'ColumnName', columnNames, ...
                'Position', [20, 20, popupWidth-40, popupHeight-70], ... % Adjusted to fit below the title
                'ColumnEditable', false); % Allow editing of table cells
         

        end

        % Button pushed function: DPlotButton
        function DPlotButtonPushed(app, event)


             % Create a progress dialog in the app
            d = uiprogressdlg(app.UIFigure, 'Title', 'Please wait...', ...
                'Message', 'Opening...', 'Indeterminate', 'on');

            % Try-catch to ensure the dialog closes even if an error occurs
            try

                % Get the current size of the UIFigure
                windowPosition = app.UIFigure.Position;

                % Extract the width and height
                window_x =  windowPosition(1);
                window_y =  windowPosition(2);

                windowWidth = windowPosition(3);
                windowHeight = windowPosition(4);

                app.res_model.plot_reservoir(window_x, window_y, windowWidth, windowHeight, 2);

            catch ME
                % Close the dialog in case of error and display the error message
                close(d);
                uialert(app.UIFigure, ME.message, 'Error');
            end


        end

        % Selection changed function: UITable_2
        function UITable_2SelectionChanged(app, event)


        end

        % Cell edit callback: UITable_2
        function UITable_2CellEdit(app, event)
            %indices = event.Indices;
            %newData = event.NewData;


            % Get updated table data (without the sum row)
            updatedData = app.UITable_2.Data(1:end-1);

            % Compute the new sum for the second column
            newSumValue = sum(cell2mat(updatedData));

            % Update the table with new sum row
            app.UITable_2.Data = [updatedData; {newSumValue}];


        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)


            %clear data of active tab

            if(app.TabGroup2.SelectedTab == app.ReservoirPropsTab)
                init_input_table1(app, 0);
            
            elseif(app.TabGroup2.SelectedTab == app.GasCompTab_2)
                init_input_table2(app, 0);
            end
            

            app.Label_notification.Text="All data is cleared.";


            %uialert(app.UIFigure, 'All data cleared!', 'Info');

        end

        % Button pushed function: ResettoDefaultsButton
        function ResettoDefaultsButtonPushed(app, event)

            %reset data of active tab

            if(app.TabGroup2.SelectedTab == app.ReservoirPropsTab)
                init_input_table1(app, 1);
            
            elseif(app.TabGroup2.SelectedTab == app.GasCompTab_2)
                init_input_table2(app, 1);
            end

            app.Label_notification.Text="Default values are set.";

        end

        % Callback function: PushTool_save
        function PushTool_saveClicked(app, event)

            if isempty(app.CurrentFileName)
                % If no file is currently selected, use "Save As"
                SaveAsButtonPushed(app, event);
            else
                % Save tables and text fields directly
                SaveAppData(app, app.CurrentFileName);
            end


        end

        % Callback function: PushTool_saveAs
        function PushTool_saveAsClicked(app, event)

            [file, path] = uiputfile('*.mat', 'Save As');
            if ischar(file)
                app.CurrentFileName = fullfile(path, file);
                % Save data to the selected file
                SaveAppData(app, app.CurrentFileName);
            end


        end

        % Callback function: PushTool_open
        function PushTool_openClicked(app, event)

            % % Open a dialog box to select a .mat file
            % [file, path] = uigetfile('*.mat', 'Open File');
            % if ischar(file)
            %     app.CurrentFileName = fullfile(path, file);
            %     % Load the table data from the file
            %     loadedData = load(app.CurrentFileName);
            %     if isfield(loadedData, 'data')
            %         app.UITable.Data = loadedData.data;
            %         uialert(app.UIFigure, 'File opened successfully!', 'Success');
            %     else
            %         uialert(app.UIFigure, 'Invalid file format.', 'Error', 'Icon', 'error');
            %     end
            % end


            [file, path] = uigetfile('*.mat', 'Open File');
            if ischar(file)
                app.CurrentFileName = fullfile(path, file);
                % Load data from the selected file
                LoadAppData(app, app.CurrentFileName);
            end

        end

        % Button pushed function: PressureDistributionButton
        function PressureDistributionButtonPushed(app, event)
            

            % Create a progress dialog in the app
            d = uiprogressdlg(app.UIFigure, 'Title', 'Please wait...', ...
                'Message', 'Opening...', 'Indeterminate', 'on');

            % Try-catch to ensure the dialog closes even if an error occurs
            try

                % Get the current size of the UIFigure
                windowPosition = app.UIFigure.Position;

                % Extract the width and height
                window_x =  windowPosition(1);
                window_y =  windowPosition(2);

                windowWidth = windowPosition(3);
                windowHeight = windowPosition(4);

                app.res_model.plot_reservoir(window_x, window_y, windowWidth, windowHeight, 3);

            catch ME
                % Close the dialog in case of error and display the error message
                close(d);
                uialert(app.UIFigure, ME.message, 'Error');
            end

        end

        % Callback function: PushTool_info
        function PushTool_infoClicked(app, event)
            

         app.createInfoPopup();


        end

        % Callback function: PushTool_help
        function PushTool_helpClicked(app, event)
            

         app.createHelpPopup();

        end

        % Button pushed function: ReservoirPropertiesButton
        function ReservoirPropertiesButtonPushed(app, event)
            
 % Define the table data and properties
    rowNames = {'Pore Volume, (Mil. m³)', 'Avg. z-Factor', 'Avg. FVF', 'Total GIP, (Mil. Sm³)','Total Hydrogen Amount in Res. (Mil. Sm³', 'Avg. Hydrogen Ratio in Res., (%)'};
    columnNames = {'At Depleted State', 'At Max. Storage Conditions'};


    %round output data
    %xxxx
        %output_table_data{1, 2} = sprintf('%.2e', output_table_data{6, 2}); % Format each number


    tableData = { sprintf('%.3f', app.output_depletedState_pore_volume), sprintf('%.3f', app.output_injectedState_pore_volume_afterFirstCycle);
        sprintf('%.4f', app.output_depletedState_Z_avg_initial),         sprintf('%.4f', app.output_injectedState_Z_avg_afterFirstCycle);
        sprintf('%.2f', app.output_depletedState_FVF_avg_initial),       sprintf('%.2f', app.output_injectedState_FVF_avg_afterFirstCycle);
        sprintf('%.1f', app.output_depletedState_GIP_initial),           sprintf('%.1f', app.output_injectedState_GIP_afterFirstCycle);
        sprintf('%.1f', app.output_depletedState_H2_volume_initial),     sprintf('%.1f', app.output_injectedState_H2_volume_afterFirstCycle);
        sprintf('%.1f', app.output_depletedState_H2_ratio_initial),      sprintf('%.1f', app.output_injectedState_H2_ratio_afterFirstCycle)};

    % Get the position of the main app window
    mainAppPos = app.UIFigure.Position; % [x, y, width, height]
    
    % Define the size of the popup window
    popupWidth = 600;
    popupHeight = 300;
    
    % Calculate the position to center the popup relative to the main app
    popupX = mainAppPos(1) + (mainAppPos(3) - popupWidth) / 2;
    popupY = mainAppPos(2) + (mainAppPos(4) - popupHeight) / 2;

    
    % Create the popup window
    popupFig = uifigure('Position', [popupX, popupY, popupWidth, popupHeight], ... % Adjusted height for title
                        'Name', 'Estimated Parameters', ...
                        'Resize', 'off');
    
    % Add a title above the table
    uilabel(popupFig, ...
            'Text', 'Estimated Reservoir and Fluid Parameters', ...
            'FontSize', 12, ...
            'FontWeight', 'bold', ...
            'HorizontalAlignment', 'left', ...
            'Position', [20, popupHeight-40, 320, 30]); % Position title above the table
    
    % Create a UITable
    uitable(popupFig, ...
            'Data', tableData, ...
            'RowName', rowNames, ...
            'ColumnName', columnNames, ...
            'Position', [20, 20, popupWidth-40, popupHeight-70], ... % Adjusted to fit below the title
            'ColumnEditable', true); % Allow editing of table cells


        end

        % Button pushed function: pZvsGIPButton
        function pZvsGIPButtonPushed(app, event)


            % Generate data
p_min = app.reservoir_pressure_depleted;
p_max = app.reservoir_pressure_injected;

pressure_selected = linspace(p_min, p_max, 3);
pressure_selected = [p_min*0.3, pressure_selected, p_max*1.2];

GIP_estimated = zeros(1,5);
z_estimated = zeros(1,5);
P_over_Z = zeros(1,5);

for i=1:5
    app.func_X_no_injection(pressure_selected(i), app.final_gas_composition_afterFirstCycle);
    GIP_estimated(i) = app.res_model.get_GIP();
    z_estimated(i) = app.res_model.get_z_factor();
    P_over_Z(i) = pressure_selected(i) / z_estimated(i);
end

%app.func_X_no_injection(p_max, app.final_gas_composition_afterFirstCycle);

x = GIP_estimated;
y = P_over_Z;

% Create table 
tableData = table(y', x', 'VariableNames', {'p/Z', 'GIP'}); 

% Create UI figure

% Get the position of the main app window
mainAppPos = app.UIFigure.Position; % [x, y, width, height]

% Define the size of the popup window
popupWidth = 700;
popupHeight = 350;

% Calculate the position to center the popup relative to the main app
popupX = mainAppPos(1) + (mainAppPos(3) - popupWidth) / 2;
popupY = mainAppPos(2) + (mainAppPos(4) - popupHeight) / 2;


fig = uifigure('Position', [popupX, popupY, popupWidth, popupHeight],'Name', 'p/Z vs GIP', 'NumberTitle', 'off');

% Create a grid layout
gl = uigridlayout(fig, [1,2]); % 1 row, 2 columns
gl.ColumnWidth = {'3x', '1x'};  % Plot takes 2x space, table takes 1x space

% Create the plot in the left column
ax = uiaxes(gl);
plot(ax, x, y, '-o', 'LineWidth', 2);
xlabel(ax, 'Gas in Place (million Sm³)');
ylabel(ax, 'p/Z (bar)');
title(ax, 'p/Z vs Gas in Place');
grid(ax, 'on');
grid(ax, 'minor');

% Select key data points
data_point_x1 = x(2);
data_point_y1 = y(2);
data_point_x2 = x(4);
data_point_y2 = y(4);

% Draw dashed reference lines
line(ax, [data_point_x1, data_point_x1], [0, data_point_y1], 'Color', 'blue', 'LineStyle', '--', 'LineWidth', 1.5);
line(ax, [0, data_point_x1], [data_point_y1, data_point_y1], 'Color', 'blue', 'LineStyle', '--', 'LineWidth', 1.5);
line(ax, [data_point_x2, data_point_x2], [0, data_point_y2], 'Color', 'red', 'LineStyle', '--', 'LineWidth', 1.5);
line(ax, [0, data_point_x2], [data_point_y2, data_point_y2], 'Color', 'red', 'LineStyle', '--', 'LineWidth', 1.5);

% Offsets for text placement
ofset_y = p_max * 3 / 100;
ofset_x = x(5) * 3 / 100;

% Add labels near lines
text(ax, ofset_x, y(2) + ofset_y, 'p/Z Base', 'FontSize', 12, 'Color', 'b', 'FontWeight', 'bold');
text(ax, ofset_x, y(4) + ofset_y, 'p/Z Top', 'FontSize', 12, 'Color', 'r', 'FontWeight', 'bold');

% Multiline annotations for gas capacity
text(ax, data_point_x1 + ofset_x, y(1), sprintf('Base\nGas\nCapacity'), 'FontSize', 12, 'Color', 'b', 'FontWeight', 'bold');
text(ax, data_point_x2 + ofset_x, y(1), sprintf('Top\nGas\nCapacity'), 'FontSize', 12, 'Color', 'r', 'FontWeight', 'bold');

% Fit and plot regression trend line
p = polyfit(x, y, 1); 
yfit = polyval(p, x);
hold(ax, 'on');
plot(ax, x, yfit, 'k-', 'LineWidth', 1.5);
hold(ax, 'off');

% Create table in the right column
uitable(gl, 'Data', tableData);


            % % 
            % % 
            % % %generate data
            % % 
            % % p_min = app.reservoir_pressure_depleted;
            % % p_max = app.reservoir_pressure_injected;
            % % 
            % % pressure_selected = linspace(p_min, p_max, 3);
            % % pressure_selected = [p_min*0.3, pressure_selected, p_max*1.2];
            % % GIP_estimated = zeros(1,5);
            % % z_estimated = zeros(1,5);
            % % P_over_Z = zeros(1,5);
            % % 
            % % for i=1:5
            % % 
            % %     % GIP_estimated(i) =  100*i;
            % %     % z_estimated(i) = 1;
            % % 
            % %     app.func_X_no_injection(pressure_selected(i), app.final_gas_composition_afterFirstCycle);
            % %     GIP_estimated(i) = app.res_model.get_GIP();
            % %     z_estimated(i) = app.res_model.get_z_factor();
            % % 
            % %     P_over_Z(i) = pressure_selected (i) / z_estimated(i);
            % % 
            % % end
            % % 
            % % app.func_X_no_injection(p_max, app.final_gas_composition_afterFirstCycle);
            % % 
            % % %disp(pressure_selected);
            % % %disp(z_estimated);
            % % %disp(P_over_Z);
            % % 
            % % 
            % % x = GIP_estimated;
            % % y = P_over_Z;
            % % 
            % % 
            % % tableData = table(x', y', 'VariableNames', {'p/Z', 'GIP'});
            % % 
            % % % Create a UI figure
            % % fig = uifigure('Name', 'Plot and Table', 'NumberTitle', 'off');
            % % 
            % % % Create a grid layout
            % % gl = uigridlayout(fig, [1,2]); % 1 row, 2 columns
            % % gl.ColumnWidth = {'2x', '1x'};  % Plot takes 2x space, table takes 1x space
            % % 
            % % % Create the plot in the left column
            % % ax = uiaxes(gl);
            % % plot(ax, x, y, '-o');
            % % 
            % % 
            % % % 2. veri noktasını belirt
            % % data_point_x1 = x(2);
            % % data_point_y1 = y(2);
            % % 
            % % % 4. veri noktasını belirt
            % % data_point_x2 = x(4);
            % % data_point_y2 = y(4);
            % % 
            % % % 2. veri noktasından y-ekseni'ne çizgi çiz
            % % line(ax, [data_point_x1, data_point_x1], [0, data_point_y1], 'Color', 'blue', 'LineStyle', '--','LineWidth', 1.5);
            % % 
            % % % 2. veri noktasından x-ekseni'ne çizgi çiz
            % % line(ax, [0, data_point_x1], [data_point_y1, data_point_y1], 'Color', 'blue', 'LineStyle', '--','LineWidth', 1.5);
            % % 
            % % % 4. veri noktasından y-ekseni'ne çizgi çiz
            % % line(ax, [data_point_x2, data_point_x2], [0, data_point_y2], 'Color', 'red', 'LineStyle', '--','LineWidth', 1.5);
            % % 
            % % % 4. veri noktasından x-ekseni'ne çizgi çiz
            % % line(ax, [0, data_point_x2], [data_point_y2, data_point_y2], 'Color', 'red', 'LineStyle', '--','LineWidth', 1.5);
            % % 
            % % % % Yatay çizgilerin üzerine metin ekle
            % % % text(data_point_x1*0.01, data_point_y1, 'p/Z Base', 'Color', 'blue', 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'left');
            % % % text(data_point_x1*0.01, data_point_y2, 'p/Z Top', 'Color', 'blue', 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'left');
            % % %
            % % % % Metni sararak ekle
            % % % wrapped_text = sprintf('Base\nGas\nCapacity');
            % % % text(data_point_x1*1.01, data_point_y1/10, wrapped_text, 'Color', 'blue', 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'left');
            % % %
            % % % wrapped_text = sprintf('Top\nGas\nCapacity');
            % % % text(data_point_x2*1.01, data_point_y1/10, wrapped_text, 'Color', 'blue', 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'left');
            % % 
            % % 
            % % ofset_y=p_max*3/100;
            % % ofset_x=x(5)*3/100;
            % % 
            % % text(ax, 5, y(2) + ofset_y, 'p/Z Base', 'FontSize', 12, 'Color', 'b', 'FontWeight', 'bold');
            % % text(ax, 5, y(4) + ofset_y, 'p/Z Top', 'FontSize', 12, 'Color', 'r', 'FontWeight', 'bold');
            % % 
            % % wrapped_text = sprintf('Base\nGas\nCapacity');
            % % text(ax, x(2) + ofset_x, y(1), wrapped_text, 'FontSize', 12, 'Color', 'b', 'FontWeight', 'bold');
            % % wrapped_text = sprintf('Top\nGas\nCapacity');
            % % text(ax, x(4)+ ofset_x, y(1) , wrapped_text, 'FontSize', 12, 'Color', 'r', 'FontWeight', 'bold');
            % % 
            % % 
            % % % Trend çizgisi ekle
            % % p = polyfit(x, y, 1); % Doğrusal regresyon
            % % yfit = polyval(p, x);
            % % plot(x, yfit, 'k-', 'LineWidth', 1.0); % Trend çizgisi
            % % 
            % % % Etiketler ve başlık ekle (isteğe bağlı)
            % % title(ax, 'Gas in Place vs p/Z');
            % % xlabel(ax, 'Gas in Place (million Sm³)');
            % % ylabel(ax, 'p/Z (bar)');
            % % % **Enable Grids**
            % % grid on;        % Enable major grid lines
            % % grid minor;     % Enable minor grid lines
            % % 
            % % 
            % % 
            % % % Create the table in the right column
            % % uitable(gl, 'Data', tableData);


        end

        % Button pushed function: GasCompositionButton
        function GasCompositionButtonPushed(app, event)
            
            table_data = transpose(app.final_gas_composition_afterFirstCycle);
            table_data = table_data * 100;

            % Convert to string array with 2 decimal places
            formattedArray = arrayfun(@(x) sprintf('%.1f', x), table_data, 'UniformOutput', false);

            % Define the table data and properties

            rowNames = app.gas_component_names;
            columnNames = {'Mole percent, %'};


            % Get the position of the main app window
            mainAppPos = app.UIFigure.Position; % [x, y, width, height]

            % Define the size of the popup window
            popupWidth = 300;
            popupHeight = 360;

            % Calculate the position to center the popup relative to the main app
            popupX = mainAppPos(1) + (mainAppPos(3) - popupWidth) / 2;
            popupY = mainAppPos(2) + (mainAppPos(4) - popupHeight) / 2;


            % Create the popup window
            popupFig = uifigure('Position', [popupX, popupY, popupWidth, popupHeight], ... % Adjusted height for title
                'Name', 'Final gas composition after gas injection', ...
                'Resize', 'off');

            % Add a title above the table
            uilabel(popupFig, ...
                'Text', 'Final gas composition after gas injection', ...
                'FontSize', 12, ...
                'FontWeight', 'bold', ...
                'HorizontalAlignment', 'left', ...
                'Position', [20, popupHeight-40, 320, 30]); % Position title above the table

            % Create a UITable
            uitable(popupFig, ...
                'Data', formattedArray, ...
                'RowName', rowNames, ...
                'ColumnName', columnNames, ...
                'Position', [20, 20, popupWidth-40, popupHeight-70], ... % Adjusted to fit below the title
                'ColumnEditable', false); % Allow editing of table cells


        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;

            app.InjGasHydrogenRatioEditField.Value = changingValue;

            
        end

        % Value changed function: InjGasHydrogenRatioEditField
        function InjGasHydrogenRatioEditFieldValueChanged2(app, event)
            value = app.InjGasHydrogenRatioEditField.Value;

            app.Slider.Value = value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool_save = uipushtool(app.Toolbar);
            app.PushTool_save.Tooltip = {'Save'};
            app.PushTool_save.ClickedCallback = createCallbackFcn(app, @PushTool_saveClicked, true);
            app.PushTool_save.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'saved.svg');
            app.PushTool_saveAs = uipushtool(app.Toolbar);
            app.PushTool_saveAs.Tooltip = {'Save As..'};
            app.PushTool_saveAs.ClickedCallback = createCallbackFcn(app, @PushTool_saveAsClicked, true);
            app.PushTool_saveAs.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'saveCopyAs.svg');
            app.PushTool_open = uipushtool(app.Toolbar);
            app.PushTool_open.Tooltip = {'Open'};
            app.PushTool_open.ClickedCallback = createCallbackFcn(app, @PushTool_openClicked, true);
            app.PushTool_open.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'openFolder.svg');
            app.PushTool_info = uipushtool(app.Toolbar);
            app.PushTool_info.ClickedCallback = createCallbackFcn(app, @PushTool_infoClicked, true);
            app.PushTool_info.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'info.svg');
            app.PushTool_info.Separator = 'on';
            app.PushTool_help = uipushtool(app.Toolbar);
            app.PushTool_help.ClickedCallback = createCallbackFcn(app, @PushTool_helpClicked, true);
            app.PushTool_help.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'help.svg');
            app.OutputsPanel = uipanel(app.UIFigure);
            app.OutputsPanel.Title = 'Outputs';
            app.DPlotButton = uibutton(app.OutputsPanel, 'push');
            app.DPlotButton.ButtonPushedFcn = createCallbackFcn(app, @DPlotButtonPushed, true);
            app.DPlotButton.Enable = 'off';
            app.DPlotButton.Tooltip = {'3-D plot of reservoir model'};
            app.DPlotButton.Text = '3-D Plot';
            app.PressureDistributionButton = uibutton(app.OutputsPanel, 'push');
            app.PressureDistributionButton.ButtonPushedFcn = createCallbackFcn(app, @PressureDistributionButtonPushed, true);
            app.PressureDistributionButton.Enable = 'off';
            app.PressureDistributionButton.Tooltip = {'Pressure distribution in the reservoir after gas injection'};
            app.PressureDistributionButton.Text = 'Pressure Distribution';
            app.CuhsionGasCompbyCycleButton = uibutton(app.OutputsPanel, 'push');
            app.CuhsionGasCompbyCycleButton.ButtonPushedFcn = createCallbackFcn(app, @CuhsionGasCompbyCycleButtonPushed, true);
            app.CuhsionGasCompbyCycleButton.Enable = 'off';
            app.CuhsionGasCompbyCycleButton.Tooltip = {'Cuhsion gas composition by storage cycle'};
            app.CuhsionGasCompbyCycleButton.Text = 'Cuhsion Gas Comp. by Cycle';
            app.NumberofCyclesLabel = uilabel(app.OutputsPanel);
            app.NumberofCyclesLabel.Enable = 'off';
            app.NumberofCyclesLabel.Text = '# of Storage Cycles:';
            app.ofStorageCyclesEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.ofStorageCyclesEditField.Limits = [1 5];
            app.ofStorageCyclesEditField.RoundFractionalValues = 'on';
            app.ofStorageCyclesEditField.Enable = 'off';
            app.ofStorageCyclesEditField.Value = 1;
            app.WorkingGasCapacityMilSm3Label = uilabel(app.OutputsPanel);
            app.WorkingGasCapacityMilSm3Label.Tooltip = {'Total working gas capacity'};
            app.WorkingGasCapacityMilSm3Label.Text = 'Working Gas Capacity, (Mil. Sm3)';
            app.WorkingGasCapacityMilSm3EditField = uieditfield(app.OutputsPanel, 'numeric');
            app.WorkingGasCapacityMilSm3EditField.Limits = [0 Inf];
            app.WorkingGasCapacityMilSm3EditField.Editable = 'off';
            app.WorkingGasCapacityMilSm3EditField.Tooltip = {'Total working gas capacity'};
            app.HydrogenStorageCapacityMilSm3EditFieldLabel = uilabel(app.OutputsPanel);
            app.HydrogenStorageCapacityMilSm3EditFieldLabel.Tooltip = {'Total hydrogen storage capacity'};
            app.HydrogenStorageCapacityMilSm3EditFieldLabel.Text = 'Hydrogen Storage Capacity, (Mil. Sm3)';
            app.HydrogenStorageCapacityMilSm3EditField = uieditfield(app.OutputsPanel, 'numeric');
            app.HydrogenStorageCapacityMilSm3EditField.Limits = [0 Inf];
            app.HydrogenStorageCapacityMilSm3EditField.Editable = 'off';
            app.HydrogenStorageCapacityMilSm3EditField.Tooltip = {'Total hydrogen storage capacity'};
            app.HydrogenRatioinResLabel = uilabel(app.OutputsPanel);
            app.HydrogenRatioinResLabel.Tooltip = {'Total hydrogen ratio in reservoir after gas injection'};
            app.HydrogenRatioinResLabel.Text = 'Hydrogen Ratio in Reservoir, (%)';
            app.HydrogenRatioinReservoirEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.HydrogenRatioinReservoirEditField.Limits = [0 Inf];
            app.HydrogenRatioinReservoirEditField.Editable = 'off';
            app.HydrogenRatioinReservoirEditField.Tooltip = {'Total hydrogen ratio in reservoir after gas injection'};
            app.ReservoirPropertiesButton = uibutton(app.OutputsPanel, 'push');
            app.ReservoirPropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @ReservoirPropertiesButtonPushed, true);
            app.ReservoirPropertiesButton.Enable = 'off';
            app.ReservoirPropertiesButton.Tooltip = {''};
            app.ReservoirPropertiesButton.Text = 'Reservoir Properties';
            app.pZvsGIPButton = uibutton(app.OutputsPanel, 'push');
            app.pZvsGIPButton.ButtonPushedFcn = createCallbackFcn(app, @pZvsGIPButtonPushed, true);
            app.pZvsGIPButton.Enable = 'off';
            app.pZvsGIPButton.Tooltip = {'p/Z vs Gas in Place Chart'};
            app.pZvsGIPButton.Text = 'p/Z vs GIP';
            app.GasCompositionButton = uibutton(app.OutputsPanel, 'push');
            app.GasCompositionButton.ButtonPushedFcn = createCallbackFcn(app, @GasCompositionButtonPushed, true);
            app.GasCompositionButton.Enable = 'off';
            app.GasCompositionButton.Tooltip = {'Gas composition after gas injection'};
            app.GasCompositionButton.Text = 'Gas Composition';
            app.BuildReservoirModelButton = uibutton(app.UIFigure, 'push');
            app.BuildReservoirModelButton.ButtonPushedFcn = createCallbackFcn(app, @BuildReservoirModelButtonPushed, true);
            app.BuildReservoirModelButton.Text = 'Build Reservoir Model';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Enable = 'off';
            app.CalculateButton.Text = 'Calculate';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.ReservoirPropsTab = uitab(app.TabGroup2);
            app.ReservoirPropsTab.Tooltip = {'Reservoir Properties'};
            app.ReservoirPropsTab.Title = 'Reservoir Props.';
            app.UITable = uitable(app.ReservoirPropsTab);
            app.UITable.ColumnName = {'Property'; 'Value'};
            app.UITable.RowName = {};
            app.GasCompTab_2 = uitab(app.TabGroup2);
            app.GasCompTab_2.Tooltip = {'Gas Composition'};
            app.GasCompTab_2.Title = 'Gas Comp.';
            app.UITable_2 = uitable(app.GasCompTab_2);
            app.UITable_2.ColumnName = {'Mole Percent (%)'};
            app.UITable_2.RowName = {};
            app.UITable_2.CellEditCallback = createCallbackFcn(app, @UITable_2CellEdit, true);
            app.UITable_2.SelectionChangedFcn = createCallbackFcn(app, @UITable_2SelectionChanged, true);
            app.StorageSettingsPanel = uipanel(app.UIFigure);
            app.StorageSettingsPanel.Title = 'Storage Settings';
            app.MinPressureBarEditFieldLabel = uilabel(app.StorageSettingsPanel);
            app.MinPressureBarEditFieldLabel.Text = 'Min. Pressure, (Bar)';
            app.MinPressureBarEditField = uieditfield(app.StorageSettingsPanel, 'numeric');
            app.MinPressureBarEditField.Limits = [0 Inf];
            app.MinPressureBarEditField.Tooltip = {'Minimum Reservoir Pressure'};
            app.MinPressureBarEditField.Value = 50;
            app.MaxPressureBarEditFieldLabel = uilabel(app.StorageSettingsPanel);
            app.MaxPressureBarEditFieldLabel.Text = 'Max. Pressure, (Bar)';
            app.MaxPressureBarEditField = uieditfield(app.StorageSettingsPanel, 'numeric');
            app.MaxPressureBarEditField.Limits = [0 Inf];
            app.MaxPressureBarEditField.Tooltip = {'Maximum Reservoir Pressure'};
            app.MaxPressureBarEditField.Value = 120;
            app.Slider = uislider(app.StorageSettingsPanel);
            app.Slider.MajorTicks = [0 20 40 60 80 100];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 100;
            app.InjGasHydrogenRatioEditFieldLabel = uilabel(app.StorageSettingsPanel);
            app.InjGasHydrogenRatioEditFieldLabel.Text = 'Inj. Gas Hydrogen Ratio, (%)';
            app.InjGasHydrogenRatioEditField = uieditfield(app.StorageSettingsPanel, 'numeric');
            app.InjGasHydrogenRatioEditField.Limits = [0 100];
            app.InjGasHydrogenRatioEditField.RoundFractionalValues = 'on';
            app.InjGasHydrogenRatioEditField.ValueChangedFcn = createCallbackFcn(app, @InjGasHydrogenRatioEditFieldValueChanged2, true);
            app.InjGasHydrogenRatioEditField.Tooltip = {'Hydrogen ratio of injected gas'};
            app.InjGasHydrogenRatioEditField.Value = 100;
            app.ClearAllButton = uibutton(app.UIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.ResettoDefaultsButton = uibutton(app.UIFigure, 'push');
            app.ResettoDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @ResettoDefaultsButtonPushed, true);
            app.ResettoDefaultsButton.Text = 'Reset to Defaults';
            app.Panel = uipanel(app.UIFigure);
            app.Label_notification = uilabel(app.Panel);
            app.Label_notification.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UHS_CCT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end